classdef Andrea_PAppdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PosicioninicialEditField        matlab.ui.control.NumericEditField
        PosicioninicialEditFieldLabel   matlab.ui.control.Label
        IngreseDatosPanel               matlab.ui.container.Panel
        GraficarButton                  matlab.ui.control.Button
        GravedadEditField               matlab.ui.control.NumericEditField
        GravedadEditFieldLabel          matlab.ui.control.Label
        CoeficientedefriccionEditField  matlab.ui.control.NumericEditField
        CoeficientedefriccinLabel       matlab.ui.control.Label
        CoeficientedearrastreEditField  matlab.ui.control.NumericEditField
        CoeficientedearrastreLabel      matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        VelocidadinicialEditField       matlab.ui.control.NumericEditField
        VelocidadinicialEditFieldLabel  matlab.ui.control.Label
        DeltatEditField_2               matlab.ui.control.NumericEditField
        DeltatEditField_2Label          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
 
xpriman = xprima0;

yn = (xprima0.^3/500) - (3*xprima0.^2)/35 + 3*cos((2*3.141592*xprima0)/15) + 30;
y0 = yn;

h0 = 5.85;
h = yn;

mu = -1.5;
k = 2.5;
r0 = 100000;


% Vía
x_via = 0:40;
y_via =(x_via.^3/500) - (3*x_via.^2)/35 + 3*cos((2*3.141592*x_via)/15) + 30;
hold on;
plot(x_via,y_via,'r');


% Gráfica
box on;
grid on;

while xpriman <= 34.99


   %Energías
   Ek = 0.5*m*v0^2;
   Ep = m*g*(h-h0);   

   % Angulo de posición y radio
   syms x
   y = (x^3/500) - (3*x^2) / 35 + 3*cosd(2*pi*x/15) + 30;
   derivada = diff(y);
   dobledev= diff(y,'x',2);
   ang = double(atand(subs(derivada,x,xn)));

   rn = (1+(double(subs(dobledev,x,xn)))^2)^3/2 /(double(subs(dobledev,x,xn)));


   % Velocidad
   %raizv = v0^2-2*g*(h-h0);
   raizv = (v0.^2*(1-(mu/r0)*(xpriman-xprima0)))-(2*g*(h-h0))-(2*g*mu*(xn-x0));
   raizv = raizv/(1+(mu/rn)*(xpriman-xprima0));

   if raizv > 0
       v = sign(v0)*sqrt(raizv);
       mu = sign(mu)*mu;
       disp("pos")

   elseif raizv <= 0
       v = -v0;
       mu = -mu;
       disp("neg")
    
   end


   % Desplazamiento del carrito
   xpriman = xprima0 + v * del_t;
   xn = x0 + (xpriman - xprima0)*cosd(ang);
   yn = (xpriman.^3/500) - (3*xpriman.^2)/35 + 3*cos((2*3.141592*xpriman)/15) + 30;

   
   % Actualizar valores de variables
   v0 = v;
   x0 = xn;
   xprima0 = xpriman;
   y0 = yn;
   h = yn;
   r0 = rn;


   % Punto que se mueve
   carro = plot(xpriman,yn,'db');
   drawnow
   pause(t/100)
   delete(carro);

    
end
hold off;

        end

        % Value changed function: DeltatEditField_2
        function DeltatEditField_2ValueChanged(app, event)
            value = app.DeltatEditField_2.Value;
            t= del_t;
        end

        % Value changed function: VelocidadinicialEditField
        function VelocidadinicialEditFieldValueChanged(app, event)
            value = app.VelocidadinicialEditField.Value; 
            v0 = VelocidadinicialEditFieldValueChanged;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: PosicioninicialEditField
        function PosicioninicialEditFieldValueChanged(app, event)
            value = app.PosicioninicialEditField.Value;
            xn = PosicininicialEditFieldValueChanged; 
            x0 = xn;
            xprima0 = xn;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Equipo 3: f(x)= (x^3/500)-(3x^2/35)+3cos(2πx/15)+30')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.IngreseDatosPanel = uipanel(app.UIFigure);
            app.IngreseDatosPanel.Title = 'Ingrese Datos';
            app.DeltatEditField_2Label = uilabel(app.IngreseDatosPanel);
            app.DeltatEditField_2Label.Text = 'Delta t';
            app.DeltatEditField_2 = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.DeltatEditField_2.ValueChangedFcn = createCallbackFcn(app, @DeltatEditField_2ValueChanged, true);
            app.VelocidadinicialEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.VelocidadinicialEditFieldLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.VelocidadinicialEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidadinicialEditFieldValueChanged, true);
            app.MasaEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.MasaEditField.Value = 10;
            app.CoeficientedearrastreLabel = uilabel(app.IngreseDatosPanel);
            app.CoeficientedearrastreLabel.Text = {'Coeficiente '; 'de arrastre'};
            app.CoeficientedearrastreEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.CoeficientedefriccinLabel = uilabel(app.IngreseDatosPanel);
            app.CoeficientedefriccinLabel.Text = {'Coeficiente '; 'de friccion'};
            app.CoeficientedefriccionEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.GravedadEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.GravedadEditFieldLabel.Text = 'Gravedad';
            app.GravedadEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.GravedadEditField.Value = 9.81;
            app.GraficarButton = uibutton(app.IngreseDatosPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PosicioninicialEditFieldLabel = uilabel(app.UIFigure);
            app.PosicioninicialEditFieldLabel.Text = 'Posicion inicial';
            app.PosicioninicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicioninicialEditField.ValueChangedFcn = createCallbackFcn(app, @PosicioninicialEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Andrea_PAppdesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end