classdef Andrea_PAppdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IngreseDatosPanel               matlab.ui.container.Panel
        PosicinInicialEditField         matlab.ui.control.EditField
        PosicinInicialEditFieldLabel    matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        GravedadEditField               matlab.ui.control.NumericEditField
        GravedadEditFieldLabel          matlab.ui.control.Label
        CoeficientedefriccionEditField  matlab.ui.control.NumericEditField
        CoeficientedefriccinLabel       matlab.ui.control.Label
        CoeficientedearrastreEditField  matlab.ui.control.NumericEditField
        CoeficientedearrastreLabel      matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        VelocidadinicialEditField       matlab.ui.control.NumericEditField
        VelocidadinicialEditFieldLabel  matlab.ui.control.Label
        DeltatEditField_2               matlab.ui.control.NumericEditField
        DeltatEditField_2Label          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: GraficarButton, UIAxes, UIAxes2
        function GraficarButtonPushed(app, event)
% Datos
g = app.GravedadEditField.Value;
m = app.MasaEditField.Value;
del_t = app.DeltatEditField_2.Value;
t = del_t;
v0 = app.VelocidadinicialEditField.Value;
v = v0;
xn = app.PosicinInicialEditField.Value;
x0 = xn;
xprima0 = 0;
yn = (x0.^3/500) - (3*x0.^2)/35 + 3*cos((2*3.141592*x0)/15) + 30;
y0 = yn;
mu = app.CoeficientedefriccionEditField.Value;
k = app.CoeficientedearrastreEditField.Value;
r0 = 1000000;

% Vía
hold on;
x_via = 0:40;
y_via =(x_via.^3/500) - (3*x_via.^2)/35 + 3*cos((2*3.141592*x_via)/15) + 30;
plot(app.UIAxes,x_via,y_via);

while xn >= 0
    Ek = 0.5*m*v0.^2;
    Ep = m*g*(yn-y0);

    % Pre_velocidad
    syms x
    y = (x^3/500) - (3*x^2) / 35 + 3*cosd(2*pi*x/15) + 30;
    derivada = diff(y);
    dobledev= diff(y,'x',2);
    ang = double(atand(subs(derivada,x,xn)));

    rn = ((1+double(subs(derivada,x,xn)).^2).^3/2)/double(subs(dobledev,x,xn));
    fric = g*cos(ang)+(v0.^2/r0);
    fric = mu*sign(fric);

    if rn == 0
        rn = 100000000000;
    end

    % Desplazamiento
    xpriman = xprima0 + v * del_t;
    xn = x0 + (xpriman - xprima0).*cosd(ang);
    yn = (xn.^3/500) - (3.*xn.^2)/35 + 3.*cos((2*3.141592*xn)/15) + 30;

    % Velocidad
    raizArriba1 = (v0.^2.*(1-((fric/r0)+(k/m)).*(abs(xpriman-xprima0))));
    raizArriba2 = (2.*g.*(yn-y0))-(2.*g.*fric.*(abs(xn-x0)));
    raizArriba = raizArriba1 - raizArriba2;
    raizAbajo = (1+((fric/rn)+(k/m)).*(abs(xpriman-xprima0)));
    raizv = raizArriba/raizAbajo;

    if raizv > 0
        v = sign(v0)*sqrt(raizv);

    elseif raizv <= 0
        v = -v0;
        xpriman = xprima0;
        xn = x0;
        yn = y0;
        rn = r0;

    end
    % Actualiza
    v0 = v;
    x0 = xn;
    xprima0 = xpriman;
    y0 = yn;
    r0 = rn;
    
    % Carrito y Energía
    y = [Ek, Ep];
    carrito = plot(app.UIAxes,xn,yn,"db");
    bar(app.UIAxes2,5,y,"stacked");
    drawnow
    pause(t/10)
    delete(carrito);
end
hold off;
        end

        % Value changed function: DeltatEditField_2
        function DeltatEditField_2ValueChanged(app, event)

        end

        % Value changed function: VelocidadinicialEditField
        function VelocidadinicialEditFieldValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function PosicioninicialEditFieldValueChanged(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Equipo 3: f(x)= (x^3/500)-(3x^2/35)+3cos(2πx/15)+30')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.IngreseDatosPanel = uipanel(app.UIFigure);
            app.IngreseDatosPanel.Title = 'Ingrese Datos';
            app.DeltatEditField_2Label = uilabel(app.IngreseDatosPanel);
            app.DeltatEditField_2Label.Text = 'Delta t';
            app.DeltatEditField_2 = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.DeltatEditField_2.ValueChangedFcn = createCallbackFcn(app, @DeltatEditField_2ValueChanged, true);
            app.DeltatEditField_2.Value = 0.03;
            app.VelocidadinicialEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.VelocidadinicialEditFieldLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.VelocidadinicialEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidadinicialEditFieldValueChanged, true);
            app.VelocidadinicialEditField.Value = -40;
            app.MasaEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.MasaEditField.Value = 100;
            app.CoeficientedearrastreLabel = uilabel(app.IngreseDatosPanel);
            app.CoeficientedearrastreLabel.Text = {'Coeficiente '; 'de arrastre'};
            app.CoeficientedearrastreEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.CoeficientedearrastreEditField.Value = 2.5;
            app.CoeficientedefriccinLabel = uilabel(app.IngreseDatosPanel);
            app.CoeficientedefriccinLabel.Text = {'Coeficiente '; 'de friccion'};
            app.CoeficientedefriccionEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.CoeficientedefriccionEditField.Value = 1.5;
            app.GravedadEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.GravedadEditFieldLabel.Text = 'Gravedad';
            app.GravedadEditField = uieditfield(app.IngreseDatosPanel, 'numeric');
            app.GravedadEditField.Value = 9.81;
            app.GraficarButton = uibutton(app.IngreseDatosPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PosicinInicialEditFieldLabel = uilabel(app.IngreseDatosPanel);
            app.PosicinInicialEditFieldLabel.Text = 'Posición Inicial';
            app.PosicinInicialEditField = uieditfield(app.IngreseDatosPanel, 'text');
            app.PosicinInicialEditField.Value = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Andrea_PAppdesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end